classdef Exam1MatMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FlashButton                    matlab.ui.control.Button
        PeriodmsEditField              matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        NumberofFlashesEditField       matlab.ui.control.NumericEditField
        NumberofFlashesEditFieldLabel  matlab.ui.control.Label
        ServerResponseTextArea         matlab.ui.control.TextArea
        ServerResponseLabel            matlab.ui.control.Label
        LEDOffButton                   matlab.ui.control.Button
        LEDOnButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        LED 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LED=Exam1LED();
        end

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            app.LED.sendCommand("led/on")
            app.ServerResponseTextArea.Value="LED On";
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            app.LED.sendCommand("led/off")
            app.ServerResponseTextArea.Value="LED Off";
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            app.LED.sendCommand(['flash/', num2str(app.NumberofFlashesEditField.Value), '/', num2str(app.PeriodmsEditField.Value)])
            app.ServerResponseTextArea.Value=["Flashes = " app.NumberofFlashesEditField.Value " " "PeriodMs = " app.PeriodmsEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDOnButton = uibutton(app.UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.ServerResponseLabel = uilabel(app.UIFigure);
            app.ServerResponseLabel.Text = {'Server '; 'Response'};
            app.ServerResponseTextArea = uitextarea(app.UIFigure);
            app.NumberofFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFlashesEditFieldLabel.Text = {'Number'; 'of Flashes'};
            app.NumberofFlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam1MatMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end