classdef byobPris < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        byobPrisFigure         matlab.ui.Figure
        Save3J4                matlab.ui.control.Label
        Save2J4                matlab.ui.control.Label
        Save1J4                matlab.ui.control.Label
        J4ValueEditField       matlab.ui.control.NumericEditField
        J4ValueEditFieldLabel  matlab.ui.control.Label
        Joint4Slider           matlab.ui.control.Slider
        Joint4SliderLabel      matlab.ui.control.Label
        Save3J3                matlab.ui.control.Label
        Save3J2                matlab.ui.control.Label
        Save3J1                matlab.ui.control.Label
        Save2J3                matlab.ui.control.Label
        Save2J2                matlab.ui.control.Label
        Save2J1                matlab.ui.control.Label
        SetPosition3Button     matlab.ui.control.Button
        SetPosition2Button     matlab.ui.control.Button
        SetPosition1Button     matlab.ui.control.Button
        GotoPosition3Button    matlab.ui.control.Button
        GotoPosition2Button    matlab.ui.control.Button
        GotoPosition1Button    matlab.ui.control.Button
        Save1J3                matlab.ui.control.Label
        Save1J2                matlab.ui.control.Label
        Save1J1                matlab.ui.control.Label
        HOMEButton             matlab.ui.control.Button
        GOButton               matlab.ui.control.Button
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        jointAngles = [0 0 0 0];
        jointExtend = [0 0 0 6];

        savedAngles1 = [0 0 0 0];
        savedAngles2 = [0 0 0 0];
        savedAngles3 = [0 0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0, 0, 90, app.jointAngles(1));
            A2 = create_A_matrix(31, 0, 0, app.jointAngles(2));
            A3 = create_A_matrix(0, 0, -90, app.jointAngles(3));
            A4 = create_A_matrix(0, app.jointExtend(4), 0, 0);

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1* A2;
            T0_3 = A1 * A2 * A3;
            T0_4 = A1 * A2 * A3 * A4;

            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;

            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [230 40];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("byobP0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("byobP1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("byobP2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("byobP3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("byobP4.stl", app.axes_arm, [35/255 181/255 24/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            app.updateArm()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1)=changingValue;
            
            app.J1ValueEditField.Value = app.jointAngles(1);

            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2)=changingValue;
            
            app.J2ValueEditField.Value = app.jointAngles(2);

            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3)=changingValue;
            
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;
            app.jointExtend(4) = app.J4ValueEditField.Value+6;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointExtend(4)-6;

            app.updateArm
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = 0;
            app.jointAngles(3) = 0;
            app.jointExtend(4) = 6;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointExtend(4)-6;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointExtend(4)-6;

            app.updateArm
        end

        % Button pushed function: SetPosition1Button
        function SetPosition1ButtonPushed(app, event)
            app.Save1J1.Text = num2str(app.jointAngles(1));
            app.Save1J2.Text = num2str(app.jointAngles(2));
            app.Save1J3.Text = num2str(app.jointAngles(3));
            app.Save1J4.Text = num2str(app.jointExtend(4)-6);
        end

        % Button pushed function: SetPosition2Button
        function SetPosition2ButtonPushed(app, event)
            app.Save2J1.Text = num2str(app.jointAngles(1));
            app.Save2J2.Text = num2str(app.jointAngles(2));
            app.Save2J3.Text = num2str(app.jointAngles(3));
            app.Save2J4.Text = num2str(app.jointExtend(4)-6);
        end

        % Button pushed function: SetPosition3Button
        function SetPosition3ButtonPushed(app, event)
            app.Save3J1.Text = num2str(app.jointAngles(1));
            app.Save3J2.Text = num2str(app.jointAngles(2));
            app.Save3J3.Text = num2str(app.jointAngles(3));
            app.Save3J4.Text = num2str(app.jointExtend(4)-6);
        end

        % Button pushed function: GotoPosition1Button
        function GotoPosition1ButtonPushed(app, event)
            app.jointAngles(1) = str2num(app.Save1J1.Text);
            app.jointAngles(2) = str2num(app.Save1J2.Text);
            app.jointAngles(3) = str2num(app.Save1J3.Text);
            app.jointExtend(4) = str2num(app.Save1J4.Text)+6;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointExtend(4)-6;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointExtend(4)-6;

            app.updateArm
        end

        % Button pushed function: GotoPosition2Button
        function GotoPosition2ButtonPushed(app, event)
            app.jointAngles(1) = str2num(app.Save2J1.Text);
            app.jointAngles(2) = str2num(app.Save2J2.Text);
            app.jointAngles(3) = str2num(app.Save2J3.Text);
            app.jointExtend(4) = str2num(app.Save2J4.Text)+6;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointExtend(4)-6;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointExtend(4)-6;

            app.updateArm
        end

        % Button pushed function: GotoPosition3Button
        function GotoPosition3ButtonPushed(app, event)
            app.jointAngles(1) = str2num(app.Save3J1.Text);
            app.jointAngles(2) = str2num(app.Save3J2.Text);
            app.jointAngles(3) = str2num(app.Save3J3.Text);
            app.jointExtend(4) = str2num(app.Save3J4.Text)+6;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.J4ValueEditField.Value = app.jointExtend(4);

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
            app.Joint4Slider.Value = app.jointExtend(4);

            app.updateArm
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointExtend(4)=changingValue+6;
            
            app.J4ValueEditField.Value = (app.jointExtend(4)-6);

            app.updateArm()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.byobPrisFigure = uifigure('Visible', 'off');
            app.byobPrisFigure.Name = 'MATLAB App';
            app.axes_arm = uiaxes(app.byobPrisFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1SliderLabel = uilabel(app.byobPrisFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.byobPrisFigure);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.Step = 1;
            app.Joint2SliderLabel = uilabel(app.byobPrisFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.byobPrisFigure);
            app.Joint2Slider.Limits = [0 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.Step = 1;
            app.Joint3SliderLabel = uilabel(app.byobPrisFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.byobPrisFigure);
            app.Joint3Slider.Limits = [-180 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.Step = 1;
            app.J1ValueEditFieldLabel = uilabel(app.byobPrisFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.byobPrisFigure, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.byobPrisFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.byobPrisFigure, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.byobPrisFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.byobPrisFigure, 'numeric');
            app.GOButton = uibutton(app.byobPrisFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.byobPrisFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.Save1J1 = uilabel(app.byobPrisFigure);
            app.Save1J1.Text = '0';
            app.Save1J2 = uilabel(app.byobPrisFigure);
            app.Save1J2.Text = '0';
            app.Save1J3 = uilabel(app.byobPrisFigure);
            app.Save1J3.Text = '0';
            app.GotoPosition1Button = uibutton(app.byobPrisFigure, 'push');
            app.GotoPosition1Button.ButtonPushedFcn = createCallbackFcn(app, @GotoPosition1ButtonPushed, true);
            app.GotoPosition1Button.Text = {'Go to '; 'Position 1'};
            app.GotoPosition2Button = uibutton(app.byobPrisFigure, 'push');
            app.GotoPosition2Button.ButtonPushedFcn = createCallbackFcn(app, @GotoPosition2ButtonPushed, true);
            app.GotoPosition2Button.Text = {'Go to '; 'Position 2'};
            app.GotoPosition3Button = uibutton(app.byobPrisFigure, 'push');
            app.GotoPosition3Button.ButtonPushedFcn = createCallbackFcn(app, @GotoPosition3ButtonPushed, true);
            app.GotoPosition3Button.Text = {'Go to '; 'Position 3'};
            app.SetPosition1Button = uibutton(app.byobPrisFigure, 'push');
            app.SetPosition1Button.ButtonPushedFcn = createCallbackFcn(app, @SetPosition1ButtonPushed, true);
            app.SetPosition1Button.Text = {'Set '; 'Position 1'};
            app.SetPosition2Button = uibutton(app.byobPrisFigure, 'push');
            app.SetPosition2Button.ButtonPushedFcn = createCallbackFcn(app, @SetPosition2ButtonPushed, true);
            app.SetPosition2Button.Text = {'Set '; 'Position 2'};
            app.SetPosition3Button = uibutton(app.byobPrisFigure, 'push');
            app.SetPosition3Button.ButtonPushedFcn = createCallbackFcn(app, @SetPosition3ButtonPushed, true);
            app.SetPosition3Button.Text = {'Set '; 'Position 3'};
            app.Save2J1 = uilabel(app.byobPrisFigure);
            app.Save2J1.Text = '0';
            app.Save2J2 = uilabel(app.byobPrisFigure);
            app.Save2J2.Text = '0';
            app.Save2J3 = uilabel(app.byobPrisFigure);
            app.Save2J3.Text = '0';
            app.Save3J1 = uilabel(app.byobPrisFigure);
            app.Save3J1.Text = '0';
            app.Save3J2 = uilabel(app.byobPrisFigure);
            app.Save3J2.Text = '0';
            app.Save3J3 = uilabel(app.byobPrisFigure);
            app.Save3J3.Text = '0';
            app.Joint4SliderLabel = uilabel(app.byobPrisFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.byobPrisFigure);
            app.Joint4Slider.Limits = [0 20];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint4Slider.Step = 1;
            app.J4ValueEditFieldLabel = uilabel(app.byobPrisFigure);
            app.J4ValueEditFieldLabel.Text = 'J4 Value';
            app.J4ValueEditField = uieditfield(app.byobPrisFigure, 'numeric');
            app.Save1J4 = uilabel(app.byobPrisFigure);
            app.Save1J4.Text = '0';
            app.Save2J4 = uilabel(app.byobPrisFigure);
            app.Save2J4.Text = '0';
            app.Save3J4 = uilabel(app.byobPrisFigure);
            app.Save3J4.Text = '0';
            % Show the figure after all components are created
            app.byobPrisFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = byobPris

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.byobPrisFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.byobPrisFigure)
        end
    end
end