classdef GoldGuesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GoldGuesserUIFigure  matlab.ui.Figure
        Euryale              matlab.ui.control.Image
        GithImage            matlab.ui.control.Image
        LabelSuccessValue    matlab.ui.control.Label
        LabelPercentValue    matlab.ui.control.Label
        LabelAttemptValue    matlab.ui.control.Label
        PercentWrongLabel    matlab.ui.control.Label
        FailuresLabel        matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button_1             matlab.ui.control.Button
    end

    
    properties (Access = private)
        GithPositions
        CardPositions
        GithImageSources
        Attempts=0
        Success=0
    end
    
    methods (Access = private)
        
        function makeAGuess(app, githIndex)
            app.Attempts=app.Attempts+1;
            app.GithImage.Position=app.GithPositions(githIndex,:);
            cardIndex=randi([1,3]);
            app.Euryale.Position=app.CardPositions(cardIndex,:);
            if cardIndex==githIndex
                app.GithImage.ImageSource=app.GithImageSources(1);
                app.Success=app.Success+1;
            else
                app.GithImage.ImageSource=app.GithImageSources(2);
            end

            app.LabelAttemptValue.Text=string(app.Attempts);
            app.LabelSuccessValue.Text=string(app.Success);
            app.LabelPercentValue.Text=num2str((app.Success/app.Attempts)*100,0);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.GithImageSources = ["./githjack.png","./Jackgith.png"];

            app.GithPositions(1,:)=[60,213,100,100];
            app.GithPositions(2,:)=[248,213,100,100];
            app.GithPositions(3,:)=[437,213,100,100];
            
            app.CardPositions(1,:)=[60,26,100,100];
            app.CardPositions(2,:)=[248,27,100,100];
            app.CardPositions(3,:)=[437,33,100,100];
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            %app.GithImage.ImageSource=app.GithImageSources(1);
            %app.GithImage.Position=app.GithPositions(1,:);
            app.makeAGuess(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %app.GithImage.ImageSource=app.GithImageSources(1);
            %app.GithImage.Position=app.GithPositions(2,:);
            app.makeAGuess(2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %app.GithImage.ImageSource=app.GithImageSources(2);
            %app.GithImage.Position=app.GithPositions(3,:);
            app.makeAGuess(3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GoldGuesserUIFigure = uifigure('Visible', 'off');
            app.GoldGuesserUIFigure.Name = 'MATLAB App';
            app.Button_1 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AttemptsLabel = uilabel(app.GoldGuesserUIFigure);
            app.AttemptsLabel.Text = 'Attempts:';
            app.FailuresLabel = uilabel(app.GoldGuesserUIFigure);
            app.FailuresLabel.Text = 'Failures:';
            app.PercentWrongLabel = uilabel(app.GoldGuesserUIFigure);
            app.PercentWrongLabel.Text = 'Percent Wrong: ';
            app.LabelAttemptValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelAttemptValue.Text = '0';
            app.LabelPercentValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelPercentValue.Text = '%';
            app.LabelSuccessValue = uilabel(app.GoldGuesserUIFigure);
            app.LabelSuccessValue.Text = '0';
            app.GithImage = uiimage(app.GoldGuesserUIFigure);
            app.GithImage.ImageSource = fullfile(pathToMLAPP, 'Jackgith.png');
            app.Euryale = uiimage(app.GoldGuesserUIFigure);
            app.Euryale.ImageSource = fullfile(pathToMLAPP, 'euryale.png');
            % Show the figure after all components are created
            app.GoldGuesserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoldGuesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GoldGuesserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GoldGuesserUIFigure)
        end
    end
end