classdef wheelNoSliderTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_2           matlab.ui.control.Label
        BuzzerButton      matlab.ui.control.Button
        SpeedSlider       matlab.ui.control.Slider
        SpeedSliderLabel  matlab.ui.control.Label
        Label             matlab.ui.control.Label
        axes_arm          matlab.ui.control.UIAxes
    end

    
     properties (Access = private)
        mqClient
        priorKey = ''
        key

        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        jointAngles = [0 0 0 0];
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
            if ~isempty(messageStruct.payload)
                messagePayload = messageStruct.payload;
            end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if strcmp(messageType, "set_speeds")
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            write(app.mqClient, "me435/eckelbge/to_pi",messageJson)
            fprintf("Sent: %s\n",messageJson)
        end

        function updateArm(app)
            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(0, 0, app.jointAngles(1),0);
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            % TODO: Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            % TODO: Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');

            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A2 = create_A_matrix(50, 0, app.jointAngles(2),0);
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_2 = A2;
            % TODO: Use the current T matricies to transform the original patch vertices
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            % TODO: Update the patches with the newly transformed vertices
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');

            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A3 = create_A_matrix(0, 100, app.jointAngles(3),0);
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_3 = A3;
            % TODO: Use the current T matricies to transform the original patch vertices
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            % TODO: Update the patches with the newly transformed vertices
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

            % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
            A4 = create_A_matrix(50, 100, app.jointAngles(4),0);
            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_4 = A4;
            % TODO: Use the current T matricies to transform the original patch vertices
            link4VerticesWRTground = T0_4 * app.link4Vertices;
            % TODO: Update the patches with the newly transformed vertices
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/eckelbge/#", "Callback", @app.myCallback)

            % Prepare the axes_arm properties
            app.axes_arm.View = [230 40];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("wheelL0V2.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("wheelL1V2.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("wheelL1V2.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("wheelL1V2.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("wheelL1V2.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));
            % End: Code that can go into your GUI's opening function.

            updateArm(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            app.key = event.Key;
            if strcmp(app.key, app.priorKey)
                return; % The key is being held down ignore this one
            end
            app.priorKey = app.key;

            speed = round(app.SpeedSlider.Value);

            if strcmp(app.key,"uparrow")
                app.sendMessage("set_speeds",[speed speed speed speed])
                while strcmp(app.key,"uparrow")
                    if ~strcmp(app.key,"uparrow")
                        break
                    end
                    app.jointAngles(1) = app.jointAngles(1)+(2*(speed/10));
                    app.jointAngles(2) = app.jointAngles(2)+(2*(speed/10));
                    app.jointAngles(3) = app.jointAngles(3)+(2*(speed/10));
                    app.jointAngles(4) = app.jointAngles(4)+(2*(speed/10));
                    updateArm(app);
                    app.Label_2.Text = num2str(app.jointAngles(1));
                    pause(0.1)
                end
            end

            if strcmp(app.key,"downarrow")
                app.sendMessage("set_speeds",[-speed -speed -speed -speed])
                while strcmp(app.key,"downarrow")
                    if ~strcmp(app.key,"downarrow")
                        break
                    end
                    app.jointAngles(1) = app.jointAngles(1)-(2*(speed/10));
                    app.jointAngles(2) = app.jointAngles(2)-(2*(speed/10));
                    app.jointAngles(3) = app.jointAngles(3)-(2*(speed/10));
                    app.jointAngles(4) = app.jointAngles(4)-(2*(speed/10));
                    updateArm(app);
                    app.Label_2.Text = num2str(app.jointAngles(1));
                    pause(0.1)
                end
            end

            if strcmp(app.key,"leftarrow")
                app.sendMessage("set_speeds",[-speed speed speed -speed])
                while strcmp(app.key,"leftarrow")
                    if ~strcmp(app.key,"leftarrow")
                        break
                    end
                    app.jointAngles(1) = app.jointAngles(1)-(2*(speed/10));
                    app.jointAngles(2) = app.jointAngles(2)+(2*(speed/10));
                    app.jointAngles(3) = app.jointAngles(3)+(2*(speed/10));
                    app.jointAngles(4) = app.jointAngles(4)-(2*(speed/10));
                    updateArm(app);
                    app.Label_2.Text = num2str(app.jointAngles(1));
                    pause(0.1)
                end
            end

            if strcmp(app.key,"rightarrow")
                app.sendMessage("set_speeds",[speed -speed -speed speed])
                while strcmp(app.key,"rightarrow")
                    if ~strcmp(app.key,"rightarrow")
                        break
                    end
                    app.jointAngles(1) = app.jointAngles(1)+(2*(speed/10));
                    app.jointAngles(2) = app.jointAngles(2)-(2*(speed/10));
                    app.jointAngles(3) = app.jointAngles(3)-(2*(speed/10));
                    app.jointAngles(4) = app.jointAngles(4)+(2*(speed/10));
                    updateArm(app);
                    app.Label_2.Text = num2str(app.jointAngles(1));
                    pause(0.1)
                end
            end

            if strcmp(app.key,"a")
                app.sendMessage("set_speeds",[-speed -speed speed speed])
                while strcmp(app.key,"a")
                    if ~strcmp(app.key,"a")
                        break
                    end
                    app.jointAngles(1) = app.jointAngles(1)+(2*(speed/10));
                    app.jointAngles(2) = app.jointAngles(2)-(2*(speed/10));
                    app.jointAngles(3) = app.jointAngles(3)+(2*(speed/10));
                    app.jointAngles(4) = app.jointAngles(4)-(2*(speed/10));
                    updateArm(app);
                    app.Label_2.Text = num2str(app.jointAngles(1));
                    pause(0.1)
                end
            end

            if strcmp(app.key,"d")
                app.sendMessage("set_speeds",[speed speed -speed -speed])
                while strcmp(app.key,"d")
                    if ~strcmp(app.key,"d")
                        break
                    end
                    app.jointAngles(1) = app.jointAngles(1)-(2*(speed/10));
                    app.jointAngles(2) = app.jointAngles(2)+(2*(speed/10));
                    app.jointAngles(3) = app.jointAngles(3)-(2*(speed/10));
                    app.jointAngles(4) = app.jointAngles(4)+(2*(speed/10));
                    updateArm(app);
                    app.Label_2.Text = num2str(app.jointAngles(1));
                    pause(0.1)
                end
            end

        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.key = "";
            app.sendMessage("stop")
            %app.jointAngles(1) = 0;
            %app.jointAngles(2) = 0;
            %app.jointAngles(3) = 0;
            %app.jointAngles(4) = 0;
            
        end

        % Button pushed function: BuzzerButton
        function BuzzerButtonPushed(app, event)
            app.sendMessage("beep")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Click in the area below to use the keyboard.';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 40;
            app.BuzzerButton = uibutton(app.UIFigure, 'push');
            app.BuzzerButton.ButtonPushedFcn = createCallbackFcn(app, @BuzzerButtonPushed, true);
            app.BuzzerButton.Text = 'Buzzer';
            app.Label_2 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wheelNoSliderTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end