classdef servomodeltest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MiscPanel             matlab.ui.container.Panel
        BuzzerButton          matlab.ui.control.Button
        DrivingPanel          matlab.ui.container.Panel
        SpeedSlider           matlab.ui.control.Slider
        SpeedSliderLabel      matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        ServosPanel           matlab.ui.container.Panel
        TiltSlider            matlab.ui.control.Slider
        TiltSliderLabel       matlab.ui.control.Label
        PanSlider             matlab.ui.control.Slider
        PanSliderLabel        matlab.ui.control.Label
        ModesPanel            matlab.ui.container.Panel
        ServoModelButton      matlab.ui.control.Button
        CarModelButton        matlab.ui.control.Button
        StopButton            matlab.ui.control.Button
        DriveuntilLineButton  matlab.ui.control.Button
        DriveuntilWallButton  matlab.ui.control.Button
        StreamingPanel        matlab.ui.container.Panel
        TimeTimestampLabel    matlab.ui.control.Label
        RightLamp             matlab.ui.control.Lamp
        RightLampLabel        matlab.ui.control.Label
        MiddleLamp            matlab.ui.control.Lamp
        MiddleLampLabel       matlab.ui.control.Label
        LeftLamp              matlab.ui.control.Lamp
        LeftLampLabel         matlab.ui.control.Label
        StreamingSwitch       matlab.ui.control.Switch
        StreamingSwitchLabel  matlab.ui.control.Label
        axes_arm              matlab.ui.control.UIAxes
    end

    
     properties (Access = private)
        mqClient
        priorKey = ''
        key
        wheel = 1
        servo = 0

        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        jointAngles = [0 0 0 0];
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
            if ~isempty(messageStruct.payload)
                messagePayload = messageStruct.payload;
            end

            if strcmp(messageType, "line_sensor_reading")

                messagePayload = messageStruct.payload
                app.TimeTimestampLabel.Text = messagePayload.timestamp;
                app.LeftLamp.Color = messagePayload.left;
                app.MiddleLamp.Color = messagePayload.middle;
                app.RightLamp.Color = messagePayload.right;
            end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            write(app.mqClient, "me435/eckelbge/to_pi",messageJson)
            fprintf("Sent: %s\n",messageJson)
        end

        function updateArm(app)
            if app.wheel == 1
                % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
                A1 = create_A_matrix(-90, -75, app.jointAngles(1),0);
                % TODO: Use the current A matricies to form the T0_n matricies.
                T0_1 = A1;
                % TODO: Use the current T matricies to transform the original patch vertices
                link1VerticesWRTground = T0_1 * app.link1Vertices;
                % TODO: Update the patches with the newly transformed vertices
                set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
    
                % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
                A2 = create_A_matrix(90, -75, app.jointAngles(2),0);
                % TODO: Use the current A matricies to form the T0_n matricies.
                T0_2 = A2;
                % TODO: Use the current T matricies to transform the original patch vertices
                link2VerticesWRTground = T0_2 * app.link2Vertices;
                % TODO: Update the patches with the newly transformed vertices
                set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
    
                % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
                A3 = create_A_matrix(-90, 75, app.jointAngles(3),0);
                % TODO: Use the current A matricies to form the T0_n matricies.
                T0_3 = A3;
                % TODO: Use the current T matricies to transform the original patch vertices
                link3VerticesWRTground = T0_3 * app.link3Vertices;
                % TODO: Update the patches with the newly transformed vertices
                set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
    
                % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
                A4 = create_A_matrix(90, 75, app.jointAngles(4),0);
                % TODO: Use the current A matricies to form the T0_n matricies.
                T0_4 = A4;
                % TODO: Use the current T matricies to transform the original patch vertices
                link4VerticesWRTground = T0_4 * app.link4Vertices;
                % TODO: Update the patches with the newly transformed vertices
                set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
            elseif app.servo == 1
                % TODO: Create the A homogeneous transformation matrices for the given jointAngles.
                A1 = create_A_matrix(0, 0, 0, 90+app.jointAngles(1));
                A2 = create_A_matrix(-20, 60, 90+app.jointAngles(2),-90);

                % TODO: Use the current A matricies to form the T0_n matricies.
                T0_1 = A1;
                T0_2 = A1* A2;

                % TODO: Use the current T matricies to transform the original patch vertices
                link1VerticesWRTground = T0_1 * app.link1Vertices;
                link2VerticesWRTground = T0_2 * app.link2Vertices;

                % TODO: Update the patches with the newly transformed vertices
                set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
                set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/eckelbge/#", "Callback", @app.myCallback)

            % Prepare the axes_arm properties
            app.axes_arm.View = [230 40];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("Drivebase 2.0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            updateArm(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
           app.key = event.Key;
            if strcmp(app.key, app.priorKey)
                return; % The key is being held down ignore this one
            end
            app.priorKey = app.key;

            speed = round(app.SpeedSlider.Value);

            if strcmp(app.key,"uparrow")
                app.sendMessage("set_speeds",[speed speed speed speed])
                if app.wheel == 1
                    while strcmp(app.key,"uparrow")
                        if ~strcmp(app.key,"uparrow")
                            break
                        end
                        app.jointAngles(1) = app.jointAngles(1)+(2*(speed/10));
                        app.jointAngles(2) = app.jointAngles(2)+(2*(speed/10));
                        app.jointAngles(3) = app.jointAngles(3)+(2*(speed/10));
                        app.jointAngles(4) = app.jointAngles(4)+(2*(speed/10));
                        updateArm(app);
                        pause(0.1)
                    end
                end
            end

            if strcmp(app.key,"downarrow")
                app.sendMessage("set_speeds",[-speed -speed -speed -speed])
                if app.wheel == 1
                    while strcmp(app.key,"downarrow")
                        if ~strcmp(app.key,"downarrow")
                            break
                        end
                        app.jointAngles(1) = app.jointAngles(1)-(2*(speed/10));
                        app.jointAngles(2) = app.jointAngles(2)-(2*(speed/10));
                        app.jointAngles(3) = app.jointAngles(3)-(2*(speed/10));
                        app.jointAngles(4) = app.jointAngles(4)-(2*(speed/10));
                        updateArm(app);
                        pause(0.1)
                    end
                end
            end

            if strcmp(app.key,"leftarrow")
                app.sendMessage("set_speeds",[-speed speed speed -speed])
                if app.wheel == 1
                    while strcmp(app.key,"leftarrow")
                        if ~strcmp(app.key,"leftarrow")
                            break
                        end
                        app.jointAngles(1) = app.jointAngles(1)-(2*(speed/10));
                        app.jointAngles(2) = app.jointAngles(2)+(2*(speed/10));
                        app.jointAngles(3) = app.jointAngles(3)+(2*(speed/10));
                        app.jointAngles(4) = app.jointAngles(4)-(2*(speed/10));
                        updateArm(app);
                        pause(0.1)
                    end
                end
            end

            if strcmp(app.key,"rightarrow")
                app.sendMessage("set_speeds",[speed -speed -speed speed])
                if app.wheel == 1
                    while strcmp(app.key,"rightarrow")
                        if ~strcmp(app.key,"rightarrow")
                            break
                        end
                        app.jointAngles(1) = app.jointAngles(1)+(2*(speed/10));
                        app.jointAngles(2) = app.jointAngles(2)-(2*(speed/10));
                        app.jointAngles(3) = app.jointAngles(3)-(2*(speed/10));
                        app.jointAngles(4) = app.jointAngles(4)+(2*(speed/10));
                        updateArm(app);
                        pause(0.1)
                    end
                end
            end

            if strcmp(app.key,"a")
                app.sendMessage("set_speeds",[-speed -speed speed speed])
                if app.wheel == 1
                    while strcmp(app.key,"a")
                        if ~strcmp(app.key,"a")
                            break
                        end
                        app.jointAngles(1) = app.jointAngles(1)+(2*(speed/10));
                        app.jointAngles(2) = app.jointAngles(2)-(2*(speed/10));
                        app.jointAngles(3) = app.jointAngles(3)+(2*(speed/10));
                        app.jointAngles(4) = app.jointAngles(4)-(2*(speed/10));
                        updateArm(app);
                        pause(0.1)
                    end
                end
            end

            if strcmp(app.key,"d")
                app.sendMessage("set_speeds",[speed speed -speed -speed])
                if app.wheel == 1
                    while strcmp(app.key,"d")
                        if ~strcmp(app.key,"d")
                            break
                        end
                        app.jointAngles(1) = app.jointAngles(1)-(2*(speed/10));
                        app.jointAngles(2) = app.jointAngles(2)+(2*(speed/10));
                        app.jointAngles(3) = app.jointAngles(3)-(2*(speed/10));
                        app.jointAngles(4) = app.jointAngles(4)+(2*(speed/10));
                        updateArm(app);
                        pause(0.1)
                    end
                end
            end

        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.key = "";
            app.sendMessage("stop")
        end

        % Button pushed function: BuzzerButton
        function BuzzerButtonPushed(app, event)
            app.sendMessage("beep")
        end

        % Button pushed function: DriveuntilWallButton
        function DriveuntilWallButtonPushed(app, event)
            app.sendMessage("mode","drive_until_wall")
        end

        % Button pushed function: DriveuntilLineButton
        function DriveuntilLineButtonPushed(app, event)
            app.sendMessage("mode","drive_until_line")
        end

        % Value changed function: StreamingSwitch
        function StreamingSwitchValueChanged(app, event)
            value = app.StreamingSwitch.Value;
            
            if strcmp(value,"On")
                app.sendMessage("is_streaming",true)
            else
                app.sendMessage("is_streaming",false)
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sendMessage("mode","none")
        end

        % Value changing function: PanSlider
        function PanSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendMessage("pan",changingValue)
            
            if app.servo == 1
                app.jointAngles(1)=-changingValue+90;
                app.updateArm()
            end
        end

        % Value changing function: TiltSlider
        function TiltSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendMessage("tilt",changingValue)
            
            if app.servo ==1
                app.jointAngles(2)=-changingValue+90;
                app.updateArm()
            end
        end

        % Button pushed function: CarModelButton
        function CarModelButtonPushed(app, event)
            cla(app.axes_arm)

            app.jointAngles = [0 0 0 0];
            app.wheel = 1;
            app.servo = 0;

            makeStlLink("Drivebase 2.0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            app.link1Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("Mecanum roller 2.0.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            updateArm(app);
        end

        % Button pushed function: ServoModelButton
        function ServoModelButtonPushed(app, event)
            cla(app.axes_arm)

            app.jointAngles = [0 0 0 0];
            app.wheel = 0;
            app.servo = 1;

            app.TiltSlider.Value=90;
            app.PanSlider.Value=90;

            makeStlLink("servoL0V2.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.

            app.link1Patch = makeStlLink("Link1.2.0.stl", app.axes_arm, [1 0 0]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("Link2.3.0.stl", app.axes_arm, [0 1 0]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            updateArm(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.StreamingPanel = uipanel(app.UIFigure);
            app.StreamingPanel.Title = 'Streaming';
            app.StreamingSwitchLabel = uilabel(app.StreamingPanel);
            app.StreamingSwitchLabel.Text = 'Switch';
            app.StreamingSwitch = uiswitch(app.StreamingPanel, 'slider');
            app.StreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.StreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.StreamingPanel);
            app.MiddleLampLabel = uilabel(app.StreamingPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.StreamingPanel);
            app.RightLampLabel = uilabel(app.StreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.StreamingPanel);
            app.TimeTimestampLabel = uilabel(app.StreamingPanel);
            app.TimeTimestampLabel.Text = 'Time Timestamp';
            app.ModesPanel = uipanel(app.UIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveuntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilWallButtonPushed, true);
            app.DriveuntilWallButton.Text = 'Drive until Wall';
            app.DriveuntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilLineButtonPushed, true);
            app.DriveuntilLineButton.Text = 'Drive until Line';
            app.StopButton = uibutton(app.ModesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CarModelButton = uibutton(app.ModesPanel, 'push');
            app.CarModelButton.ButtonPushedFcn = createCallbackFcn(app, @CarModelButtonPushed, true);
            app.CarModelButton.Text = 'Car Model';
            app.ServoModelButton = uibutton(app.ModesPanel, 'push');
            app.ServoModelButton.ButtonPushedFcn = createCallbackFcn(app, @ServoModelButtonPushed, true);
            app.ServoModelButton.Text = 'Servo Model';
            app.ServosPanel = uipanel(app.UIFigure);
            app.ServosPanel.Title = 'Servos';
            app.PanSliderLabel = uilabel(app.ServosPanel);
            app.PanSliderLabel.Text = 'Pan';
            app.PanSlider = uislider(app.ServosPanel);
            app.PanSlider.Limits = [0 180];
            app.PanSlider.ValueChangingFcn = createCallbackFcn(app, @PanSliderValueChanging, true);
            app.PanSlider.Value = 90;
            app.TiltSliderLabel = uilabel(app.ServosPanel);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.ServosPanel);
            app.TiltSlider.Limits = [0 180];
            app.TiltSlider.ValueChangingFcn = createCallbackFcn(app, @TiltSliderValueChanging, true);
            app.TiltSlider.Value = 90;
            app.DrivingPanel = uipanel(app.UIFigure);
            app.DrivingPanel.Title = 'Driving';
            app.Label = uilabel(app.DrivingPanel);
            app.Label.Text = 'Click in the area below to use the keyboard.';
            app.SpeedSliderLabel = uilabel(app.DrivingPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.DrivingPanel);
            app.SpeedSlider.Value = 40;
            app.MiscPanel = uipanel(app.UIFigure);
            app.MiscPanel.Title = 'Misc';
            app.BuzzerButton = uibutton(app.MiscPanel, 'push');
            app.BuzzerButton.ButtonPushedFcn = createCallbackFcn(app, @BuzzerButtonPushed, true);
            app.BuzzerButton.Text = 'Buzzer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = servomodeltest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end