classdef Exam3GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Exam3MQTT           matlab.ui.Figure
        Distance0cmLabel    matlab.ui.control.Label
        BothReverseButton   matlab.ui.control.Button
        BothForwardButton   matlab.ui.control.Button
        RightReverseButton  matlab.ui.control.Button
        RightForwardButton  matlab.ui.control.Button
        LeftReverseButton   matlab.ui.control.Button
        LeftForwardButton   matlab.ui.control.Button
        RightSlider         matlab.ui.control.Slider
        RightSliderLabel    matlab.ui.control.Label
        LeftSlider          matlab.ui.control.Slider
        LeftSliderLabel     matlab.ui.control.Label
        TimeSlider          matlab.ui.control.Slider
        TimeSliderLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
            if ~isempty(messageStruct.payload)
                messagePayload = messageStruct.payload;
            end

            if messageType == "ultra"
                app.Distance0cmLabel.Text = sprintf("Distance = %d cm", round(messagePayload));
            end
        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            messageStruct.payload = messagePayload;
            messageJson = jsonencode(messageStruct);

            %app.mqClient.write("chat",messageJson);
            write(app.mqClient, "me435/eckelbge/to_pi",messageJson)
            fprintf("Sent: %s\n",messageJson)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/eckelbge/to_computer", "Callback", @app.myCallback)
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            app.sendMessage("left",[1,round(app.TimeSlider.Value),round(app.LeftSlider.Value)])
        end

        % Button pushed function: LeftReverseButton
        function LeftReverseButtonPushed(app, event)
            app.sendMessage("left",[0,round(app.TimeSlider.Value),round(app.LeftSlider.Value)])
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            app.sendMessage("right",[1,round(app.TimeSlider.Value),round(app.RightSlider.Value)])
        end

        % Button pushed function: RightReverseButton
        function RightReverseButtonPushed(app, event)
            app.sendMessage("right",[0,round(app.TimeSlider.Value),round(app.RightSlider.Value)])
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
             app.sendMessage("both",[1,round(app.TimeSlider.Value),round(app.LeftSlider.Value),round(app.RightSlider.Value)])
        end

        % Button pushed function: BothReverseButton
        function BothReverseButtonPushed(app, event)
            app.sendMessage("both",[0,round(app.TimeSlider.Value),round(app.LeftSlider.Value),round(app.RightSlider.Value)])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Exam3MQTT = uifigure('Visible', 'off');
            app.Exam3MQTT.Name = 'Exam 3 MQTT';
            app.TimeSliderLabel = uilabel(app.Exam3MQTT);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.Exam3MQTT);
            app.TimeSlider.Limits = [0.5 4];
            app.TimeSlider.Value = 1;
            app.LeftSliderLabel = uilabel(app.Exam3MQTT);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.Exam3MQTT);
            app.LeftSlider.Value = 40;
            app.RightSliderLabel = uilabel(app.Exam3MQTT);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.Exam3MQTT);
            app.RightSlider.Value = 40;
            app.LeftForwardButton = uibutton(app.Exam3MQTT, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.LeftReverseButton = uibutton(app.Exam3MQTT, 'push');
            app.LeftReverseButton.ButtonPushedFcn = createCallbackFcn(app, @LeftReverseButtonPushed, true);
            app.LeftReverseButton.Text = 'Left Reverse';
            app.RightForwardButton = uibutton(app.Exam3MQTT, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.RightReverseButton = uibutton(app.Exam3MQTT, 'push');
            app.RightReverseButton.ButtonPushedFcn = createCallbackFcn(app, @RightReverseButtonPushed, true);
            app.RightReverseButton.Text = 'Right Reverse';
            app.BothForwardButton = uibutton(app.Exam3MQTT, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.BothReverseButton = uibutton(app.Exam3MQTT, 'push');
            app.BothReverseButton.ButtonPushedFcn = createCallbackFcn(app, @BothReverseButtonPushed, true);
            app.BothReverseButton.Text = 'Both Reverse';
            app.Distance0cmLabel = uilabel(app.Exam3MQTT);
            app.Distance0cmLabel.Text = 'Distance = 0 cm';
            % Show the figure after all components are created
            app.Exam3MQTT.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam3GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Exam3MQTT)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Exam3MQTT)
        end
    end
end