classdef PrettyPartC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SETTIMEDELAYSPanel     matlab.ui.container.Panel
        RESETTODEFAULTButton   matlab.ui.control.Button
        CONFIRMCHANGESButton   matlab.ui.control.Button
        TimeDelayRowsLabel     matlab.ui.control.Label
        TimeDelayColumnsLabel  matlab.ui.control.Label
        TimeDelayTable         matlab.ui.control.Table
        SPECIALMOVESPanel      matlab.ui.container.Panel
        SWAP13Button           matlab.ui.control.Button
        SWAP24Button           matlab.ui.control.Button
        MOVEPanel              matlab.ui.container.Panel
        TOSpinner              matlab.ui.control.Spinner
        TOSpinnerLabel         matlab.ui.control.Label
        FROMSpinner            matlab.ui.control.Spinner
        FROMSpinnerLabel       matlab.ui.control.Label
        MOVEPLATEButton        matlab.ui.control.Button
        RESPONSESPanel         matlab.ui.container.Panel
        GETSTATUSButton        matlab.ui.control.Button
        RESPONSESTextArea      matlab.ui.control.TextArea
        CONNECTIONPanel        matlab.ui.container.Panel
        PORTNUMBEREditField    matlab.ui.control.EditField
        PORTNUMBERLabel        matlab.ui.control.Label
        DISCONNECTButton       matlab.ui.control.Button
        CONNECTButton          matlab.ui.control.Button
        XAXISPanel             matlab.ui.container.Panel
        Button_4               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_1               matlab.ui.control.Button
        GRIPPERPanel           matlab.ui.container.Panel
        CLOSEButton            matlab.ui.control.Button
        OPENButton             matlab.ui.control.Button
        ZAXISPanel             matlab.ui.container.Panel
        EXTENDButton           matlab.ui.control.Button
        RETRACTButton          matlab.ui.control.Button
        ImageSwapPlateLeft     matlab.ui.control.Image
        ImageSwapPlateRight    matlab.ui.control.Image
        ImageExtendBars        matlab.ui.control.Image
        ImagePlateOnly         matlab.ui.control.Image
        RESETButton            matlab.ui.control.Button
        ImageGripper           matlab.ui.control.Image
        ImageRobotBackground   matlab.ui.control.Image
    end


    properties (Access = private)
        robot
        GripperXPosition %1=186, 2=289, 3=393, 4=494, 5=595
        PlateXPosition
        GripperOpen
        PlateGrabbed
        ZAxisExtended
        LPlatePos
        RPlatePos
        MPlatePos
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot=PlateLoaderFrontend();
            myTime=[0 60 20 30 0
            0 0 30 30 0
            0 30 0 30 0
            0 30 30 0 0
            0 30 20 60 0];
            app.TimeDelayTable.Data=myTime;
            app.ImageGripper.Position=[393,481,100,100];
            app.GripperXPosition=393;
            app.PlateXPosition=393;
            app.ImagePlateOnly.Position=[app.PlateXPosition,315,100,100];
            app.GripperOpen=0;
            app.PlateGrabbed=0;
            app.ZAxisExtended=0;
            app.ImageExtendBars.Visible="off";
            app.ImageSwapPlateLeft.Visible='off';
            app.ImageSwapPlateRight.Visible='off';
            app.RESPONSESTextArea.Value="READY, SAGIAN PE LOADER, ROM VER. 1.1.6, 12APR2001";
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
           app.robot=PlateLoader(app.PORTNUMBEREditField.Value-48);
           myTime=[0 60 20 30 0
               0 0 30 30 0
               0 30 0 30 0
               0 30 30 0 0
               0 30 20 60 0];
           app.TimeDelayTable.Data=myTime;
           app.ImageGripper.Position=[393,481,100,100];
           app.GripperXPosition=393;
           app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
           app.GripperOpen=0;
           app.PlateGrabbed=0;
           app.ZAxisExtended=0;
           app.ImageExtendBars.Visible="off";
           app.ImageSwapPlateLeft.Visible='off';
           app.ImageSwapPlateRight.Visible='off';
           app.RESPONSESTextArea.Value="READY, SAGIAN PE LOADER, ROM VER. 1.1.6, 12APR2001";
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
            app.robot=PlateLoaderSim(4);
            myTime=[0 60 20 30 0
                0 0 30 30 0
                0 30 0 30 0
                0 30 30 0 0
                0 30 20 60 0];
            app.TimeDelayTable.Data=myTime;
            app.ImageGripper.Position=[393,481,100,100];
            app.GripperXPosition=393;
            app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
            app.GripperOpen=0;
            app.PlateGrabbed=0;
            app.ZAxisExtended=0;
            app.ImageExtendBars.Visible="off";
            app.ImageSwapPlateLeft.Visible='off';
            app.ImageSwapPlateRight.Visible='off';
            app.RESPONSESTextArea.Value="READY, SAGIAN PE LOADER, ROM VER. 1.1.6, 12APR2001";
        end

        % Button pushed function: RESETTODEFAULTButton
        function RESETTODEFAULTButtonPushed(app, event)
            app.robot.resetDefaultTimes
            app.TimeDelayTable.Data=[0 60 20 30 0
                0 0 30 30 0
                0 30 0 30 0
                0 30 30 0 0
                0 30 20 60 0];
            app.RESPONSESTextArea.Value="READY";
        end

        % Button pushed function: CONFIRMCHANGESButton
        function CONFIRMCHANGESButtonPushed(app, event)
            app.robot.setTimeValues(app.TimeDelayTable.Data);
            app.RESPONSESTextArea.Value="READY";
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.ImageGripper.Position=[186,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=186;
            app.RESPONSESTextArea.Value="READY";
            %app.robot.x(1)
            app.robot.sendCommand("X-AXIS 1")
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ImageGripper.Position=[289,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=289;
            app.RESPONSESTextArea.Value="READY";
            %app.robot.x(2)
            app.robot.sendCommand("X-AXIS 2")
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ImageGripper.Position=[393,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=393;
            app.RESPONSESTextArea.Value="READY";
            %app.robot.x(3)
            app.robot.sendCommand("X-AXIS 3")
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ImageGripper.Position=[494,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=494;
            app.RESPONSESTextArea.Value="READY";
            %app.robot.x(4)
            app.robot.sendCommand("X-AXIS 4")
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ImageGripper.Position=[595,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=595;
            app.RESPONSESTextArea.Value="READY";
            %app.robot.x(5)
            app.robot.sendCommand("X-AXIS 5")
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            %app.robot.reset
            app.robot.sendCommand("RESET")
            app.ImageGripper.Position=[393,481,100,100];
            app.GripperXPosition=393;
            app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
            app.GripperOpen=0;
            app.PlateGrabbed=0;
            app.ZAxisExtended=0;
            app.ImageExtendBars.Visible="off";
            app.ImageSwapPlateLeft.Visible='off';
            app.ImageSwapPlateRight.Visible='off';
            app.RESPONSESTextArea.Value="READY, SAGIAN PE LOADER, ROM VER. 1.1.6, 12APR2001";
        end

        % Button pushed function: EXTENDButton
        function EXTENDButtonPushed(app, event)
            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.RESPONSESTextArea.Value="READY, EXTENDED";
            %app.robot.extend
            app.robot.sendCommand("Z-AXIS EXTEND")
        end

        % Button pushed function: RETRACTButton
        function RETRACTButtonPushed(app, event)
            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.ZAxisExtended=0;
            app.RESPONSESTextArea.Value="READY, RETRACTED";
            %app.robot.retract
            app.robot.sendCommand("Z-AXIS RETRACT")
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            if app.PlateGrabbed == 0
                app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
                %app.robot.open
                app.robot.sendCommand("GRIPPER OPEN")
                app.GripperOpen=1;
            elseif app.PlateGrabbed == 1 && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
                app.ImagePlateOnly.Position=[app.GripperXPosition,315,100,100];
                app.PlateXPosition=app.GripperXPosition;
                app.ImagePlateOnly.Visible="on";
                %app.robot.open
                app.robot.sendCommand("GRIPPER OPEN")
                app.PlateGrabbed=0;
                app.GripperOpen=1;
            end
            app.RESPONSESTextArea.Value="READY, OPEN";
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            if app.GripperXPosition == app.PlateXPosition && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImagePlateOnly.Visible="off";
                %app.robot.close
                app.robot.sendCommand("GRIPPER CLOSE")
                app.PlateGrabbed=1;
                app.RESPONSESTextArea.Value="READY, CLOSED, PLATE";
            end
            if app.PlateGrabbed == 0 
                app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
                %app.robot.close
                app.robot.sendCommand("GRIPPER CLOSE")
                app.GripperOpen=0;
                app.RESPONSESTextArea.Value="READY, CLOSED, NOPLATE";
            end
        end

        % Button pushed function: GETSTATUSButton
        function GETSTATUSButtonPushed(app, event)
            %app.RESPONSESTextArea.Value=app.robot.getStatus;
            app.RESPONSESTextArea.Value=app.robot.sendCommand("LOADER_STATUS");
        end

        % Button pushed function: MOVEPLATEButton
        function MOVEPLATEButtonPushed(app, event)
            if app.FROMSpinner.Value==1
                app.GripperXPosition=186;
            elseif app.FROMSpinner.Value==2
                app.GripperXPosition=289;
            elseif app.FROMSpinner.Value==3
                app.GripperXPosition=393;
            elseif app.FROMSpinner.Value==4
                app.GripperXPosition=494;
            elseif app.FROMSpinner.Value==5
                app.GripperXPosition=595;
            end
            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            pause(1)
            
            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            pause(1)

            if app.GripperXPosition == app.PlateXPosition && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImagePlateOnly.Visible="off";
                %app.robot.close
                app.PlateGrabbed=1;
            end
            if app.PlateGrabbed == 0 
                app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
                %app.robot.close
                app.GripperOpen=0;
            end
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.ZAxisExtended=0;
            pause(1)

            if app.TOSpinner.Value==1
                app.GripperXPosition=186;
            elseif app.TOSpinner.Value==2
                app.GripperXPosition=289;
            elseif app.TOSpinner.Value==3
                app.GripperXPosition=393;
            elseif app.TOSpinner.Value==4
                app.GripperXPosition=494;
            elseif app.TOSpinner.Value==5
                app.GripperXPosition=595;
            end
            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImagePlateOnly.Position=[app.GripperXPosition,315,100,100];
            app.PlateXPosition=app.GripperXPosition;
            app.ImagePlateOnly.Visible="on";
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_closed_no_plate.jpg");
            app.GripperOpen=0;
            pause(1)

            app.ImageGripper.Position=[393,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=393;
            pause(1)

            %app.robot.movePlate(app.FROMSpinner.Value,app.TOSpinner.Value)
            %app.robot.sendCommand(["MOVE %s %s", app.FROMSpinner.Value,app.TOSpinner.Value])
            app.robot.sendCommand(['MOVE ', num2str(app.FROMSpinner.Value), ' ', num2str(app.TOSpinner.Value)])
            %app.robot.sendCommand("MOVE 3 5")
            app.RESPONSESTextArea.Value="READY";
        end

        % Button pushed function: SWAP24Button
        function SWAP24ButtonPushed(app, event)
            app.ImagePlateOnly.Visible="off";
            app.LPlatePos=289;
            app.ImageSwapPlateLeft.Position=[app.LPlatePos,315,100,100];
            app.ImageSwapPlateLeft.Visible="on";
            app.RPlatePos=494;
            app.ImageSwapPlateRight.Position=[app.RPlatePos,315,100,100];
            app.ImageSwapPlateRight.Visible="on";
            app.MPlatePos=595;
            pause(1)

            app.ImageGripper.Position=[app.RPlatePos,481,100,100];
            app.robot.x(4)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.RPlatePos;
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.robot.open
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)
            
            if app.GripperXPosition == app.RPlatePos && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImageSwapPlateRight.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
                pause(1)
            end

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.MPlatePos,481,100,100];
            app.robot.x(5)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.MPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImageSwapPlateRight.Position=[app.GripperXPosition,315,100,100];
            app.ImageSwapPlateRight.Visible="on";
            app.robot.open
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.LPlatePos,481,100,100];
            app.robot.x(2)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.LPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            if app.GripperXPosition == app.LPlatePos && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImageSwapPlateLeft.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
                pause(1)
            end

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.RPlatePos,481,100,100];
            app.robot.x(4)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.RPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImageSwapPlateLeft.Position=[app.GripperXPosition,315,100,100];
            app.ImageSwapPlateLeft.Visible="on";
            app.robot.open
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.MPlatePos,481,100,100];
            app.robot.x(5)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.MPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            if app.GripperXPosition == app.MPlatePos && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImageSwapPlateRight.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
                pause(1)
            end

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.LPlatePos,481,100,100];
            app.robot.x(2)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.LPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImageSwapPlateRight.Position=[app.GripperXPosition,315,100,100];
            app.ImageSwapPlateRight.Visible="on";
            app.robot.open
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.RESPONSESTextArea.Value="READY";

            pause(5)
            app.ImagePlateOnly.Visible="on";
            app.ImageSwapPlateLeft.Visible="off";
            app.ImageSwapPlateRight.Visible="off";
        end

        % Button pushed function: SWAP13Button
        function SWAP13ButtonPushed(app, event)
            app.ImagePlateOnly.Visible="off";
            app.LPlatePos=186;
            app.ImageSwapPlateLeft.Position=[app.LPlatePos,315,100,100];
            app.ImageSwapPlateLeft.Visible="on";
            app.RPlatePos=393;
            app.ImageSwapPlateRight.Position=[app.RPlatePos,315,100,100];
            app.ImageSwapPlateRight.Visible="on";
            app.MPlatePos=289;
            pause(1)

            app.ImageGripper.Position=[app.RPlatePos,481,100,100];
            app.robot.x(3)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.RPlatePos;
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.robot.open
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)
            
            if app.GripperXPosition == app.RPlatePos && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImageSwapPlateRight.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
                pause(1)
            end

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.MPlatePos,481,100,100];
            app.robot.x(2)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.MPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImageSwapPlateRight.Position=[app.GripperXPosition,315,100,100];
            app.ImageSwapPlateRight.Visible="on";
            app.robot.open
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.LPlatePos,481,100,100];
            app.robot.x(1)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.LPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            if app.GripperXPosition == app.LPlatePos && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImageSwapPlateLeft.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
                pause(1)
            end

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.RPlatePos,481,100,100];
            app.robot.x(3)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.RPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImageSwapPlateLeft.Position=[app.GripperXPosition,315,100,100];
            app.ImageSwapPlateLeft.Visible="on";
            app.robot.open
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.MPlatePos,481,100,100];
            app.robot.x(2)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.MPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            if app.GripperXPosition == app.MPlatePos && app.ZAxisExtended ==1
                app.ImageGripper.ImageSource=("./gripper_with_plate.jpg");
                app.ImageSwapPlateRight.Visible="off";
                app.robot.close
                app.PlateGrabbed=1;
                pause(1)
            end

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.ImageGripper.Position=[app.LPlatePos,481,100,100];
            app.robot.x(1)
            app.ImageExtendBars.Visible="off";
            app.GripperXPosition=app.LPlatePos;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,340,100,100];
            app.ZAxisExtended=1;
            app.ImageExtendBars.Position=[app.GripperXPosition,415,100,140];
            app.ImageExtendBars.Visible="on";
            app.robot.extend
            pause(1)

            app.ImageGripper.ImageSource=("./gripper_open_no_plate.jpg");
            app.ImageSwapPlateRight.Position=[app.GripperXPosition,315,100,100];
            app.ImageSwapPlateRight.Visible="on";
            app.robot.open
            app.PlateGrabbed=0;
            app.GripperOpen=1;
            pause(1)

            app.ImageGripper.Position=[app.GripperXPosition,481,100,100];
            app.ImageExtendBars.Visible="off";
            app.robot.retract
            app.ZAxisExtended=0;
            pause(1)

            app.RESPONSESTextArea.Value="READY";

            pause(5)
            app.ImagePlateOnly.Visible="on";
            app.ImageSwapPlateLeft.Visible="off";
            app.ImageSwapPlateRight.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageRobotBackground = uiimage(app.UIFigure);
            app.ImageRobotBackground.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.ImageGripper = uiimage(app.UIFigure);
            app.ImageGripper.ImageSource = fullfile(pathToMLAPP, 'gripper_closed_no_plate.jpg');
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ImagePlateOnly = uiimage(app.UIFigure);
            app.ImagePlateOnly.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ImageExtendBars = uiimage(app.UIFigure);
            app.ImageExtendBars.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.ImageSwapPlateRight = uiimage(app.UIFigure);
            app.ImageSwapPlateRight.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ImageSwapPlateLeft = uiimage(app.UIFigure);
            app.ImageSwapPlateLeft.ImageSource = fullfile(pathToMLAPP, 'plate_only.jpg');
            app.ZAXISPanel = uipanel(app.UIFigure);
            app.ZAXISPanel.Title = 'Z-AXIS';
            app.RETRACTButton = uibutton(app.ZAXISPanel, 'push');
            app.RETRACTButton.ButtonPushedFcn = createCallbackFcn(app, @RETRACTButtonPushed, true);
            app.RETRACTButton.Text = 'RETRACT';
            app.EXTENDButton = uibutton(app.ZAXISPanel, 'push');
            app.EXTENDButton.ButtonPushedFcn = createCallbackFcn(app, @EXTENDButtonPushed, true);
            app.EXTENDButton.Text = 'EXTEND';
            app.GRIPPERPanel = uipanel(app.UIFigure);
            app.GRIPPERPanel.Title = 'GRIPPER';
            app.OPENButton = uibutton(app.GRIPPERPanel, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.CLOSEButton = uibutton(app.GRIPPERPanel, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.XAXISPanel = uipanel(app.UIFigure);
            app.XAXISPanel.Title = 'X-AXIS';
            app.Button_1 = uibutton(app.XAXISPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_5 = uibutton(app.XAXISPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_3 = uibutton(app.XAXISPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.XAXISPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_4 = uibutton(app.XAXISPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.CONNECTIONPanel = uipanel(app.UIFigure);
            app.CONNECTIONPanel.Title = 'CONNECTION';
            app.CONNECTButton = uibutton(app.CONNECTIONPanel, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.CONNECTIONPanel, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.PORTNUMBERLabel = uilabel(app.CONNECTIONPanel);
            app.PORTNUMBERLabel.Text = {'PORT '; 'NUMBER'};
            app.PORTNUMBEREditField = uieditfield(app.CONNECTIONPanel, 'text');
            app.RESPONSESPanel = uipanel(app.UIFigure);
            app.RESPONSESPanel.Title = 'RESPONSES';
            app.RESPONSESTextArea = uitextarea(app.RESPONSESPanel);
            app.GETSTATUSButton = uibutton(app.RESPONSESPanel, 'push');
            app.GETSTATUSButton.ButtonPushedFcn = createCallbackFcn(app, @GETSTATUSButtonPushed, true);
            app.GETSTATUSButton.Text = {'GET '; 'STATUS'};
            app.MOVEPanel = uipanel(app.UIFigure);
            app.MOVEPanel.Title = 'MOVE';
            app.MOVEPLATEButton = uibutton(app.MOVEPanel, 'push');
            app.MOVEPLATEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEPLATEButtonPushed, true);
            app.MOVEPLATEButton.Text = 'MOVE PLATE';
            app.FROMSpinnerLabel = uilabel(app.MOVEPanel);
            app.FROMSpinnerLabel.Text = 'FROM:';
            app.FROMSpinner = uispinner(app.MOVEPanel);
            app.FROMSpinner.Limits = [1 5];
            app.FROMSpinner.Value = 1;
            app.TOSpinnerLabel = uilabel(app.MOVEPanel);
            app.TOSpinnerLabel.Text = 'TO:';
            app.TOSpinner = uispinner(app.MOVEPanel);
            app.TOSpinner.Limits = [1 5];
            app.TOSpinner.Value = 1;
            app.SPECIALMOVESPanel = uipanel(app.UIFigure);
            app.SPECIALMOVESPanel.Title = 'SPECIAL MOVES';
            app.SWAP24Button = uibutton(app.SPECIALMOVESPanel, 'push');
            app.SWAP24Button.ButtonPushedFcn = createCallbackFcn(app, @SWAP24ButtonPushed, true);
            app.SWAP24Button.Text = {'SWAP '; '2 & 4'};
            app.SWAP13Button = uibutton(app.SPECIALMOVESPanel, 'push');
            app.SWAP13Button.ButtonPushedFcn = createCallbackFcn(app, @SWAP13ButtonPushed, true);
            app.SWAP13Button.Text = {'SWAP'; '1 & 3'};
            app.SETTIMEDELAYSPanel = uipanel(app.UIFigure);
            app.SETTIMEDELAYSPanel.Title = 'SET TIME DELAYS';
            app.TimeDelayTable = uitable(app.SETTIMEDELAYSPanel);
            app.TimeDelayTable.ColumnName = '';
            app.TimeDelayTable.RowName = {};
            app.TimeDelayTable.ColumnEditable = true;
            app.TimeDelayColumnsLabel = uilabel(app.SETTIMEDELAYSPanel);
            app.TimeDelayColumnsLabel.Text = ' 1  2  3  4  5';
            app.TimeDelayRowsLabel = uilabel(app.SETTIMEDELAYSPanel);
            app.TimeDelayRowsLabel.Text = {'1'; '2'; '3'; '4'; '5'};
            app.CONFIRMCHANGESButton = uibutton(app.SETTIMEDELAYSPanel, 'push');
            app.CONFIRMCHANGESButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIRMCHANGESButtonPushed, true);
            app.CONFIRMCHANGESButton.Text = {'CONFIRM'; 'CHANGES'};
            app.RESETTODEFAULTButton = uibutton(app.SETTIMEDELAYSPanel, 'push');
            app.RESETTODEFAULTButton.ButtonPushedFcn = createCallbackFcn(app, @RESETTODEFAULTButtonPushed, true);
            app.RESETTODEFAULTButton.Text = {'RESET TO'; 'DEFAULT'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrettyPartC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end